classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SelectSerialPortDropDownLabel  matlab.ui.control.Label
        SelectSerialPortDropDown       matlab.ui.control.DropDown
        RefreshButton                  matlab.ui.control.Button
        ExportarButton                 matlab.ui.control.Button
        NombredeArchivoEditFieldLabel  matlab.ui.control.Label
        NombredeArchivoEditField       matlab.ui.control.EditField
        Label                          matlab.ui.control.Label
        SeleccionarButton              matlab.ui.control.StateButton
    end

    methods (Access = private)

        % Value changed function: SelectSerialPortDropDown
        function SerialPortSelect(app, event)
            global ComPort;
            global PortSelected;
            
            PortSelected = app.SelectSerialPortDropDown.Value;
            ComPort = seriallist('available');   
            app.SelectSerialPortDropDown.Items = ComPort;  

        end

        % Button pushed function: RefreshButton
        function Refresh(app, event)
   
            global ComPort;
            
            ComPort = seriallist('available');
            app.SelectSerialPortDropDown.Items = ComPort;
            
            
        end

        % Button pushed function: ExportarButton
        function Exportar(app, event)

            
            out_filename = app.NombredeArchivoEditField.Value; % guarda nombre ingresado en la campo de edición
            out_filename = strcat(out_filename, ".txt");
            [file, path] = uiputfile(out_filename); % obtiene la dirección y el nombre para guardar el archivo
            
            % en caso de que se cancele "guardar archivo"
            if file == 0   
                return;
            end
            
            fullFileName = fullfile(path, file);    % construye la ubicación del archivo
            f = fopen(fullFileName, 'w');
            fprintf(f, 'aaaaa');
            fclose(f);
        end

        % Value changed function: SeleccionarButton
        function Actualizar(app, event)
            fclose(f);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SelectSerialPortDropDownLabel = uilabel(app.UIFigure);
            app.SelectSerialPortDropDownLabel.Text = 'Select Serial Port';
            app.SelectSerialPortDropDown = uidropdown(app.UIFigure);
            app.SelectSerialPortDropDown.Items = {};
            app.SelectSerialPortDropDown.ValueChangedFcn = createCallbackFcn(app, @SerialPortSelect, true);
            app.SelectSerialPortDropDown.Value = {};
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @Refresh, true);
            app.RefreshButton.Text = 'Refresh';
            app.ExportarButton = uibutton(app.UIFigure, 'push');
            app.ExportarButton.ButtonPushedFcn = createCallbackFcn(app, @Exportar, true);
            app.ExportarButton.Text = 'Exportar';
            app.NombredeArchivoEditFieldLabel = uilabel(app.UIFigure);
            app.NombredeArchivoEditFieldLabel.Text = 'Nombre de Archivo';
            app.NombredeArchivoEditField = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end